classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        LiczenieobjtocifunkcjiobrconychwokosiOXLabel  matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        WzrfunkcjiLabel          matlab.ui.control.Label
        WzrfunkcjiEditField      matlab.ui.control.EditField
        PrzedziaLabel            matlab.ui.control.Label
        PrzedzialEditField       matlab.ui.control.NumericEditField
        RYSUJButton              matlab.ui.control.Button
        Przedzial2EditField      matlab.ui.control.NumericEditField
        StartButton              matlab.ui.control.Button
        PauzaButton              matlab.ui.control.Button
        NPrzedzial               matlab.ui.control.NumericEditField
        LiczbaprzedziawLabel     matlab.ui.control.Label
        LiczbaprzedziawSlider    matlab.ui.control.Slider
        MetodacakowaniaLabel     matlab.ui.control.Label
        MetodacakowaniaDropDown  matlab.ui.control.DropDown
        ObjtoTextAreaLabel       matlab.ui.control.Label
        ObjtoTextArea            matlab.ui.control.TextArea
    end


    properties (Access = private)
        running = 0 % Description
    end


    methods (Access = private)

        % Button pushed function: RYSUJButton
        function RYSUJButtonPushed(app, event)
            s = strcat('@(x)',app.WzrfunkcjiEditField.Value);
            fun = str2func(s);
            metoda = app.MetodacakowaniaDropDown.Value;
            przedzial = [app.PrzedzialEditField.Value app.Przedzial2EditField.Value];
            n = app.LiczbaprzedziawSlider.Value;
            if metoda == "Metoda stożków ściętych"
                V = stozek(fun,przedzial,n,app.UIAxes);
                app.ObjtoTextArea.Value = num2str(V);
                rotate3d(app.UIAxes,'on');
            elseif metoda == "Metoda walców"
                V = metodaWalcowApp(fun,przedzial,n,app.UIAxes);
                app.ObjtoTextArea.Value = num2str(V);
                rotate3d(app.UIAxes,'on');
            end
        end

        % Value changed function: LiczbaprzedziawSlider
        function LiczbaprzedziawSliderValueChanged(app, event)
            value = app.LiczbaprzedziawSlider.Value;
            value = round(value);
            app.NPrzedzial.Value = value;
            app.LiczbaprzedziawSlider.Value = value;
        end

        % Value changing function: LiczbaprzedziawSlider
        function LiczbaprzedziawSliderValueChanging(app, event)
            changingValue = event.Value;
        end

        % Callback function
        function vTextAreaValueChanged(app, event)
            value = app.vTextArea.Value;
        end

        % Value changed function: NPrzedzial
        function NPrzedzialValueChanged(app, event)
            value = app.NPrzedzial.Value;
            app.LiczbaprzedziawSlider.Value = value;
            app.NPrzedzial.Value = value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.running == 0
                app.running = 1;
                first = 1;
                t = tic;
                while app.running == 1
                    pause(0.2)
                    if toc >= 1 || first == 1
                        t = tic;
                        if first == 0
                            value = app.NPrzedzial.Value + 1;
                            if value == 101
                                value = 1;
                            end
                            app.LiczbaprzedziawSlider.Value = value;
                            app.NPrzedzial.Value = value;
                        else
                            first = 0;
                        end
                        s = strcat('@(x)',app.WzrfunkcjiEditField.Value);
                        fun = str2func(s);
                        metoda = app.MetodacakowaniaDropDown.Value;
                        przedzial = [app.PrzedzialEditField.Value app.Przedzial2EditField.Value];
                        n = app.LiczbaprzedziawSlider.Value;
                        if metoda == "Metoda stożków ściętych"
                            V = stozek(fun,przedzial,n,app.UIAxes);
                            app.ObjtoTextArea.Value = num2str(V);
                            rotate3d(app.UIAxes,'on');
                        elseif metoda == "Metoda walców"
                            V = metodaWalcowApp(fun,przedzial,n,app.UIAxes);
                            app.ObjtoTextArea.Value = num2str(V);
                            rotate3d(app.UIAxes,'on');
                        end 
                    end
                end
            end
        end

        % Button pushed function: PauzaButton
        function PauzaButtonPushed(app, event)
            app.running = 0;    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.LiczenieobjtocifunkcjiobrconychwokosiOXLabel = uilabel(app.UIFigure);
            app.LiczenieobjtocifunkcjiobrconychwokosiOXLabel.Text = 'Liczenie objętości funkcji obróconych wokół osi OX';
            app.Panel = uipanel(app.UIFigure);
            app.WzrfunkcjiLabel = uilabel(app.Panel);
            app.WzrfunkcjiLabel.Text = 'Wzór funkcji:';
            app.WzrfunkcjiEditField = uieditfield(app.Panel, 'text');
            app.WzrfunkcjiEditField.Value = '4*x^2+4*x+1';
            app.PrzedziaLabel = uilabel(app.Panel);
            app.PrzedziaLabel.Text = 'Przedział:';
            app.PrzedzialEditField = uieditfield(app.Panel, 'numeric');
            app.PrzedzialEditField.Value = -10;
            app.RYSUJButton = uibutton(app.Panel, 'push');
            app.RYSUJButton.ButtonPushedFcn = createCallbackFcn(app, @RYSUJButtonPushed, true);
            app.RYSUJButton.Text = 'RYSUJ';
            app.Przedzial2EditField = uieditfield(app.Panel, 'numeric');
            app.Przedzial2EditField.Value = 10;
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PauzaButton = uibutton(app.Panel, 'push');
            app.PauzaButton.ButtonPushedFcn = createCallbackFcn(app, @PauzaButtonPushed, true);
            app.PauzaButton.Text = 'Pauza';
            app.NPrzedzial = uieditfield(app.Panel, 'numeric');
            app.NPrzedzial.Limits = [1 100];
            app.NPrzedzial.ValueDisplayFormat = '%.0f';
            app.NPrzedzial.ValueChangedFcn = createCallbackFcn(app, @NPrzedzialValueChanged, true);
            app.NPrzedzial.Value = 2;
            app.LiczbaprzedziawLabel = uilabel(app.Panel);
            app.LiczbaprzedziawLabel.Text = 'Liczba przedziałów:';
            app.LiczbaprzedziawSlider = uislider(app.Panel);
            app.LiczbaprzedziawSlider.Limits = [1 100];
            app.LiczbaprzedziawSlider.ValueChangedFcn = createCallbackFcn(app, @LiczbaprzedziawSliderValueChanged, true);
            app.LiczbaprzedziawSlider.ValueChangingFcn = createCallbackFcn(app, @LiczbaprzedziawSliderValueChanging, true);
            app.LiczbaprzedziawSlider.Value = 2;
            app.MetodacakowaniaLabel = uilabel(app.Panel);
            app.MetodacakowaniaLabel.Text = 'Metoda całkowania:';
            app.MetodacakowaniaDropDown = uidropdown(app.Panel);
            app.MetodacakowaniaDropDown.Items = {'Metoda walców', 'Metoda stożków ściętych'};
            app.MetodacakowaniaDropDown.Value = 'Metoda walców';
            app.ObjtoTextAreaLabel = uilabel(app.Panel);
            app.ObjtoTextAreaLabel.Text = 'Objętość:';
            app.ObjtoTextArea = uitextarea(app.Panel);
            app.ObjtoTextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end